classdef serial_communication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        EchoTimeSlider_4  matlab.ui.control.Slider
        Label_38          matlab.ui.control.Label
        Label_37          matlab.ui.control.Label
        EchoSwitch_4      matlab.ui.control.Switch
        mSLabel_3         matlab.ui.control.Label
        EchoTimeLabel_4   matlab.ui.control.Label
        echo_state_4      matlab.ui.control.Lamp
        Image2            matlab.ui.control.Image
        EchoTimeSlider_3  matlab.ui.control.Slider
        Label_36          matlab.ui.control.Label
        EchoTimeLabel_3   matlab.ui.control.Label
        Label_35          matlab.ui.control.Label
        EchoSwitch_3      matlab.ui.control.Switch
        echo_state_3      matlab.ui.control.Lamp
        EchoTimeSlider_2  matlab.ui.control.Slider
        Label_34          matlab.ui.control.Label
        Label_33          matlab.ui.control.Label
        EchoSwitch_2      matlab.ui.control.Switch
        mSLabel_2         matlab.ui.control.Label
        EchoTimeLabel_2   matlab.ui.control.Label
        echo_state_2      matlab.ui.control.Lamp
        KHzLabel_3        matlab.ui.control.Label
        KHzLabel_2        matlab.ui.control.Label
        KHzLabel          matlab.ui.control.Label
        HzLabel_3         matlab.ui.control.Label
        HzLabel_2         matlab.ui.control.Label
        Button            matlab.ui.control.Button
        HzLabel           matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
        Label_32          matlab.ui.control.Label
        dBLabel_15        matlab.ui.control.Label
        dBLabel_14        matlab.ui.control.Label
        dBLabel_13        matlab.ui.control.Label
        Slider_5          matlab.ui.control.Slider
        dBLabel_12        matlab.ui.control.Label
        dBLabel_11        matlab.ui.control.Label
        dBLabel_10        matlab.ui.control.Label
        Slider_4          matlab.ui.control.Slider
        dBLabel_9         matlab.ui.control.Label
        dBLabel_8         matlab.ui.control.Label
        dBLabel_7         matlab.ui.control.Label
        Slider_3          matlab.ui.control.Slider
        dBLabel_6         matlab.ui.control.Label
        dBLabel_5         matlab.ui.control.Label
        dBLabel_4         matlab.ui.control.Label
        Slider_2          matlab.ui.control.Slider
        dBLabel_3         matlab.ui.control.Label
        dBLabel_2         matlab.ui.control.Label
        dBLabel           matlab.ui.control.Label
        Slider_1          matlab.ui.control.Slider
        Label_31          matlab.ui.control.Label
        UpSampleSwitch    matlab.ui.control.Switch
        UpSampleNum       matlab.ui.control.DropDown
        UpSample_state    matlab.ui.control.Lamp
        Label_30          matlab.ui.control.Label
        DownSampleSwitch  matlab.ui.control.Switch
        DropDown          matlab.ui.control.DropDown
        Label_29          matlab.ui.control.Label
        Sample            matlab.ui.control.Label
        DownSample_state  matlab.ui.control.Lamp
        ReverbTimeSlider  matlab.ui.control.Slider
        Label_25          matlab.ui.control.Label
        mSLabel_1         matlab.ui.control.Label
        ReverbTimeLabel   matlab.ui.control.Label
        mSLabel           matlab.ui.control.Label
        EchoTimeLabel     matlab.ui.control.Label
        EchoTimeSlider    matlab.ui.control.Slider
        Label_24          matlab.ui.control.Label
        Image             matlab.ui.control.Image
        Label_22          matlab.ui.control.Label
        EqaSwitch         matlab.ui.control.Switch
        eqa_state         matlab.ui.control.Lamp
        Label_21          matlab.ui.control.Label
        ReverbSwitch      matlab.ui.control.Switch
        reverb_state      matlab.ui.control.Lamp
        echo_state        matlab.ui.control.Lamp
        Label_20          matlab.ui.control.Label
        EchoSwitch        matlab.ui.control.Switch
        SerialSend        matlab.ui.control.EditField
        Label_18          matlab.ui.control.Label
        serial_state      matlab.ui.control.Lamp
        Label_14          matlab.ui.control.Label
        SerialReceiver    matlab.ui.control.EditField
        Label_13          matlab.ui.control.Label
        SerialParity      matlab.ui.control.DropDown
        Label_6           matlab.ui.control.Label
        SerialPortNums    matlab.ui.control.DropDown
        Label_5           matlab.ui.control.Label
        SerialStopBit     matlab.ui.control.DropDown
        Label_4           matlab.ui.control.Label
        setLabel          matlab.ui.control.Label
        SerialSwitch      matlab.ui.control.Switch
        Label             matlab.ui.control.Label
        SerialBaud        matlab.ui.control.DropDown
        Label_2           matlab.ui.control.Label
    end

    
    properties (Access = private)
        SerialObject;%串口对象
    end
    
    methods (Access = private)
         %% 串口接受的数据处理
        function Deal_serial_data(app)
              if app.SerialObject.UserData{1}(1:2)=="##"   %如果帧头为 ## 就表示接受的数据正确
                    if app.SerialObject.UserData{1}(3)~='3' %当接受的命令不等于 '3'的时候，就赋值knife_state
                        if app.SerialObject.UserData{1}(3)=='2'%分闸与合闸信号
                            app.Lamp.Color=[0,1,0];%绿色 合闸 将指示灯打开
                            app.Label_lamp.Text='合闸';   
                        else
                            app.Lamp.Color=[1,1,1];%白色 分闸  将指示灯关闭
                            app.Label_lamp.Text='分闸';
                        end
                    end
                    app.Label_15.Text=app.SerialObject.UserData{1}(4:7);   %为采样电流
                    app.current_SampleVaule.Value=str2double(app.Label_15.Text);
                    app.SerialReceiver.Value=app.SerialObject.UserData;%将接收的数据显示在串口接受控件上
              end
        end
        
        function sendSliderData(app, gainValue, sliderNum)
            try
                % 6位二进制的地址（根据 sliderNum 来决定）
                addressBinary = dec2bin(6 + sliderNum, 6);  % 从地址 7 开始

                % 将 gainValue 转换为 HEX 格式的命令数据
                % 这里直接发送1、2、3来代表-6, 0, 6
                gainNum = find([-6, 0, 6] == gainValue);  % 将-6, 0, 6映射为1, 2, 3

                % 构造 24 位二进制字符串
                dataBinary = [addressBinary, dec2bin(gainNum, 18)];

                % 将二进制数据转换为 HEX 格式带空格
                hexData = [dec2hex(bin2dec(dataBinary(1:8)), 2), ' ', ...
                    dec2hex(bin2dec(dataBinary(9:16)), 2), ' ', ...
                    dec2hex(bin2dec(dataBinary(17:24)), 2)];

                % 转换为字节数组并发送到串口
                byteData = uint8(sscanf(hexData, '%2x').');
                write(app.SerialObject, byteData, "uint8");

                % 在 SerialSend 控件上显示发送的数据
                app.SerialSend.Value = hexData;

            catch
                errordlg('error: 串口未连接！');
            end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %串口创建
            com_list=serialportlist("all");%找到可以用的串口号 
           try
                app.SerialPortNums.Items=com_list;
                app.SerialBaud.Value="9600";  
           catch
                app.portData.PortName='null';
                errordlg('没有搜索到任何可用端口');
            end    
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);       
        end

        % Drop down opening function: SerialPortNums
        function SerialPortNumsDropDownOpening(app, event)
             %串口创建
            com_list=serialportlist("all");%找到可以用的串口号 
           try
                app.SerialPortNums.Items=com_list;
                app.SerialBaud.Value="9600";  
           catch
                app.portData.PortName='null';
                errordlg('没有搜索到任何可用端口');
            end      
        end

        % Value changed function: SerialSwitch
        function SerialSwitchValueChanged(app, event)
            value = app.SerialSwitch.Value;
            if (value == "打开")
                try
                    % 打开串口
                    app.SerialObject = serialport( ...
                        app.SerialPortNums.Value, ...            % 设置端口号
                        str2double(app.SerialBaud.Value), ...    % 设置波特率
                        'StopBits', str2double(app.SerialStopBit.Value), ... % 设置停止位
                        'Parity', app.SerialParity.Value);       % 设置校验位
                    configureTerminator(app.SerialObject, "LF");  % 设置行终止符为 "LF"
                    configureCallback(app.SerialObject, "terminator", @readSerialData); % 设置回调函数
                    app.serial_state.Color = [0, 1, 0];          % 绿色表示串口已打开

                    % 发送两次 "00 00 00" 数据
                    hexData = "00 00 00";
                    byteData = uint8(sscanf(hexData, '%2x').');  % 转换为字节数组
                    write(app.SerialObject, byteData, "uint8");  % 第一次发送
                    pause(0.1);                                  % 添加稍微的延时，避免数据冲突
                    write(app.SerialObject, byteData, "uint8");  % 第二次发送

                catch
                    errordlg('error:该串口可能被别的应用占用或串口命名错误！');
                    app.SerialSwitch.Value = '关闭';
                    return
                end
            elseif (value == "关闭")
                % 关闭串口
                delete(app.SerialObject);
                app.serial_state.Color = [1, 1, 1];              % 白色表示串口已关闭
            end

            % 串口接受数据的回调函数
            function readSerialData(src, event)
                app.SerialObject.UserData = readline(app.SerialObject);
                Deal_serial_data(app);  % 处理接收到的串口数据
            end
        end

        % Value changed function: EchoSwitch
        function EchoSwitchValueChanged(app, event)
            value = app.EchoSwitch.Value;
            try
                % 判断开关状态并发送对应的数据
                if strcmp(value, '打开')
                    % 开状态下发送 24 位 HEX 数据 00 00 04
                    hexData = "F4 00 04";
                    app.echo_state.Color=[0,1,0];%绿色  将指示灯打开
                elseif strcmp(value, '关闭')
                    % 关状态下发送 24 位 HEX 数据 00 00 00
                    hexData = "F4 00 00";
                    app.echo_state.Color=[1,1,1];%白色  将指示灯关闭
                end
                % 将 HEX 数据转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');
                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");
                % 显示发送的数据在 SerialSend 控件中
                app.SerialSend.Value = hexData;
            catch
                errordlg('error: 串口未连接！');
            end
        end

        % Value changed function: ReverbSwitch
        function ReverbSwitchValueChanged(app, event)
            value = app.ReverbSwitch.Value;
            try
                % 判断开关状态并发送对应的数据
                if strcmp(value, '打开')
                    % 开状态下发送 24 位 HEX 数据 00 00 08
                    hexData = "F4 00 08";
                    app.reverb_state.Color=[0,1,0];%绿色  将指示灯打开
                elseif strcmp(value, '关闭')
                    % 关状态下发送 24 位 HEX 数据 00 00 00
                    hexData = "F4 00 00";
                    app.reverb_state.Color=[1,1,1];%白色  将指示灯关闭
                end
                % 将 HEX 数据转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');
                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");
                % 显示发送的数据在 SerialSend 控件中
                app.SerialSend.Value = hexData;
            catch
                errordlg('error: 串口未连接！');
            end
        end

        % Value changed function: EqaSwitch
        function EqaSwitchValueChanged(app, event)
            value = app.EqaSwitch.Value;
            try
                % 判断开关状态并发送对应的数据
                if strcmp(value, '打开')
                    % 开状态下发送 24 位 HEX 数据 00 00 10
                    hexData = "F8 00 10";
                    app.eqa_state.Color=[0,1,0];%绿色  将指示灯打开
                elseif strcmp(value, '关闭')
                    % 关状态下发送 24 位 HEX 数据 00 00 00
                    hexData = "F8 00 00";
                    app.eqa_state.Color=[1,1,1];%白色  将指示灯关闭
                end
                % 将 HEX 数据转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');
                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");
                % 显示发送的数据在 SerialSend 控件中
                app.SerialSend.Value = hexData;
            catch
                errordlg('error: 串口未连接！');
            end
        end

        % Value changed function: EchoTimeSlider
        function EchoTimeSliderValueChanged(app, event)
            try
                % 检查串口是否已打开
                numel(app.SerialObject); % 如果串口未连接，将会抛出异常

                % 获取滑动条当前整数值
                sliderValue = round(app.EchoTimeSlider.Value);

                % 将当前值显示在标签上
                app.EchoTimeLabel.Text = num2str(sliderValue);

                % 将滑动条值乘以48，并确保是整数
                value = sliderValue * 48;

                % 将结果转换为18位二进制字符串，不足前面补零
                binaryValue = dec2bin(value, 18);

                % 地址为6位二进制 '010001'，即6'd7
                addressBinary = '000111';

                % 合并地址和数据，形成24位二进制字符串
                combinedBinary = strcat(addressBinary, binaryValue);

                % 将24位二进制转换为3个字节的十六进制字符串格式
                hexData = [dec2hex(bin2dec(combinedBinary(1:8)), 2), ' ', ...
                    dec2hex(bin2dec(combinedBinary(9:16)), 2), ' ', ...
                    dec2hex(bin2dec(combinedBinary(17:24)), 2)];

                % 将HEX数据字符串转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');

                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");

                % 在SerialSend控件上显示发送的数据
                app.SerialSend.Value = hexData;

            catch
                errordlg('error: 串口未连接！');
            end
        end

        % Value changed function: ReverbTimeSlider
        function ReverbTimeSliderValueChanged(app, event)
                try
                % 检查串口是否已打开
                numel(app.SerialObject); % 如果串口未连接，将会抛出异常

                % 获取滑动条当前整数值
                sliderValue = round(app.ReverbTimeSlider.Value);

                % 将当前值显示在标签上
                app.ReverbTimeLabel.Text = num2str(sliderValue);

                % 将滑动条值乘以48，并确保是整数
                value = sliderValue * 48;

                % 将结果转换为18位二进制字符串，不足前面补零
                binaryValue = dec2bin(value, 18);

                % 地址为6位二进制 '010001'，即6'd10
                addressBinary = '001010';

                % 合并地址和数据，形成24位二进制字符串
                combinedBinary = strcat(addressBinary, binaryValue);

                % 将24位二进制转换为3个字节的十六进制字符串格式
                hexData = [dec2hex(bin2dec(combinedBinary(1:8)), 2), ' ', ...
                    dec2hex(bin2dec(combinedBinary(9:16)), 2), ' ', ...
                    dec2hex(bin2dec(combinedBinary(17:24)), 2)];

                % 将HEX数据字符串转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');

                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");

                % 在SerialSend控件上显示发送的数据
                app.SerialSend.Value = hexData;

            catch
                errordlg('error: 串口未连接！');
            end
        end

        % Value changed function: DropDown
        function DownValueChanged(app, event)
            try
                % 获取下拉框的值
                value = app.DropDown.Value;  % 获取的是字符串类型

                % 根据不同的 value 值选择对应的 HEX 数据
                switch value
                    case '12'
                        hexData = "20 00 4D";
                    case '16'
                        hexData = "20 00 3C";
                    case '24'
                        hexData = "20 00 2A";
                    otherwise
                        errordlg('未定义的值！');
                        return;
                end

                % 将 HEX 数据转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');

                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");

                % 显示发送的数据在 SerialSend 控件中
                app.SerialSend.Value = hexData;

            catch
                errordlg('error: 串口未连接！');
            end     
        end

        % Value changed function: DownSampleSwitch
        function DownSampleSwitchChanged(app, event)
            value = app.DownSampleSwitch.Value;
            try
                % 判断开关状态并发送对应的数据
                if strcmp(value, '打开')
                    % 开状态下发送 24 位 HEX 数据 00 00 02
                    hexData = "00 00 02";
                    app.DownSample_state.Color=[0,1,0];%绿色  将指示灯打开
                    app.Sample.Text = app.DropDown.Value;  % 获取 DropDown 控件的值
                elseif strcmp(value, '关闭')
                    % 关状态下发送 24 位 HEX 数据 00 00 00
                    hexData = "00 00 00";
                    app.DownSample_state.Color=[1,1,1];%白色  将指示灯关闭
                    app.Sample.Text = "48";  % 设置值为 "48"
                end
                % 将 HEX 数据转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');
                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");
                % 显示发送的数据在 SerialSend 控件中
                app.SerialSend.Value = hexData;
            catch
                errordlg('error: 串口未连接！');
            end
        end

        % Value changed function: UpSampleSwitch
        function UpSampleSwitchChanged(app, event)
            value = app.UpSampleSwitch.Value;
            try
                % 判断开关状态并发送对应的数据
                if strcmp(value, '打开')
                    % 开状态下发送 24 位 HEX 数据 00 00 01
                    hexData = "00 00 01";
                    app.UpSample_state.Color=[0,1,0];%绿色  将指示灯打开
                    app.Sample.Text = app.UpSampleNum.Value;  % 获取 DropDown 控件的值
                elseif strcmp(value, '关闭')
                    % 关状态下发送 24 位 HEX 数据 00 00 00
                    hexData = "00 00 00";
                    app.UpSample_state.Color=[1,1,1];%白色  将指示灯关闭
                    app.Sample.Text = "48";  % 设置值为 "48"
                end
                % 将 HEX 数据转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');
                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");
                % 显示发送的数据在 SerialSend 控件中
                app.SerialSend.Value = hexData;
            catch
                errordlg('error: 串口未连接！');
            end
        end

        % Value changed function: UpSampleNum
        function UpSampleNumValueChanged(app, event)
            value = app.UpSampleNum.Value;
            try
                % 根据不同的 value 值选择对应的 HEX 数据
                switch value
                    case '96'
                        hexData = "24 00 14";
                    case '192'
                        hexData = "24 00 32";
                    case '384'
                        hexData = "24 00 70";
                    otherwise
                        errordlg('未定义的值！');
                        return;
                end

                % 将 HEX 数据转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');

                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");

                % 显示发送的数据在 SerialSend 控件中
                app.SerialSend.Value = hexData;

            catch
                errordlg('error: 串口未连接！');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
    % 获取截止频率
    Fc = app.EditField.Value;
    % 固定的采样率
    Fs = 48000; % 或者使用 app 中的其他采样率变量
    
    % 1. 调用设计函数，生成系数文件
    designButterworthFilter(Fs, Fc);
    
    % 2. 从文件中读取系数
    coeff_a_file = './data/coeff_a.dat';
    coeff_b_file = './data/coeff_b.dat';
    
    coeff_a_fi = load(coeff_a_file);
    coeff_b_fi = load(coeff_b_file);
    
    % 3. 定义地址
    addresses = [1, 2, 3, 4, 5, 6]; % 6-bit addresses from 1 to 6
    
    % 4. 格式化并发送数据
    coeff_data = [coeff_a_fi; coeff_b_fi];
    
    for i = 1:length(coeff_data)
        try
            % 组合 24 位数据：6 位地址 + 18 位数据
            address = addresses(i);
            data = coeff_data(i);
            
            % 将数据组合为 24 位整数
            packet = bitshift(uint32(address), 18) + uint32(data);
            
            % 将 24 位整数转换为二进制字符串
            combinedBinary = dec2bin(packet, 24);
            
            % 将二进制字符串转换为以空格分隔的 HEX 格式
            hexData = [dec2hex(bin2dec(combinedBinary(1:8)), 2), ' ', ...
                       dec2hex(bin2dec(combinedBinary(9:16)), 2), ' ', ...
                       dec2hex(bin2dec(combinedBinary(17:24)), 2)];
            
            % 将 HEX 字符串转换为字节数组
            byteData = uint8(sscanf(hexData, '%2x').');
            
            % 发送数据到串口
            write(app.SerialObject, byteData, "uint8");
            
            % 在 SerialSend 控件中显示发送的数据
            app.SerialSend.Value = hexData;
            
            % 添加发送间隔，避免发送过快
            pause(0.01); % 10ms 间隔
        catch
            errordlg('error: 串口未连接！');
            return;
        end
    end
    
    disp('系数已发送。');


        end

        % Value changed function: Slider_1
        function SliderValueChanged_1(app, event)
            % 获取当前滑块值
            value = round(app.Slider_1.Value);
            gain = 10^(value / 20);

            % 设定采样率和频率范围
            Fs = 48000;
            f1 = 20;
            f2 = 80;

            % 调用封装的函数生成滤波器系数文件
            generate_filter_coefficients(Fs, f1, f2, gain);

            % 从生成的文件中读取系数
            coeff_a_file = './data/coeff_a_gain1.dat'; % 假设增益文件生成名称可以统一处理
            coeff_b_file = './data/coeff_b_gain1.dat'; % 修改为实际增益值的文件路径

            try
                coeff_a_fi = load(coeff_a_file);
                coeff_b_fi = load(coeff_b_file);
            catch
                errordlg('系数文件未找到或加载失败');
                return;
            end
            % 3. 定义地址 (添加新地址范围)
            addresses = [15, 16, 17, 18, 19, 20]; % 使用 15 到 20 作为地址

            % 4. 格式化并发送数据
            coeff_data = [coeff_a_fi; coeff_b_fi];

            for i = 1:length(coeff_data)
                try
                    % 检查地址索引
                    if i > length(addresses)
                        break;
                    end

                    % 组合 24 位数据：6 位地址 + 18 位数据
                    address = addresses(i);
                    if coeff_data(i) < 0
                        data = bitand(int32(coeff_data(i)), 2^18 - 1);  % 负数取18位补码
                    else
                        data = uint32(coeff_data(i));  % 正数直接转换
                    end
           
                    disp(data);
                    % 将地址左移18位并与数据组合为24位
                    packet = bitshift(address, 18) + data;

                    % 转换为24位二进制字符串，再分段为8位HEX并带空格
                    hexData = sprintf('%02X %02X %02X', bitshift(packet, -16), bitand(bitshift(packet, -8), 255), bitand(packet, 255));
             

                    % 将 HEX 字符串转换为字节数组
                    byteData = uint8(sscanf(hexData, '%2x').');

                    % 发送数据到串口
                    write(app.SerialObject, byteData, "uint8");

                    % 在 SerialSend 控件中显示发送的数据
                    app.SerialSend.Value = hexData;

                    % 添加发送间隔，避免发送过快
                    pause(0.01); % 10ms 间隔
                catch
                    errordlg('error: 串口未连接！');
                    return;
                end
            end

            disp('系数已发送。');
   
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            % 获取当前滑块值

            value = round(app.Slider_2.Value);
            gain = 10^(value / 20);

            % 设定采样率和频率范围
            Fs = 48000;
            f1 = 80;
            f2 = 320;

            % 调用封装的函数生成滤波器系数文件
            generate_filter_coefficients(Fs, f1, f2, gain);

            % 从生成的文件中读取系数
            coeff_a_file = './data/coeff_a_gain1.dat'; % 假设增益文件生成名称可以统一处理
            coeff_b_file = './data/coeff_b_gain1.dat'; % 修改为实际增益值的文件路径

            try
                coeff_a_fi = load(coeff_a_file);
                coeff_b_fi = load(coeff_b_file);
            catch
                errordlg('系数文件未找到或加载失败');
                return;
            end
            % 3. 定义地址 (添加新地址范围)
            addresses = [21, 22, 23, 24, 25, 26]; % 使用 15 到 20 作为地址

            % 4. 格式化并发送数据
            coeff_data = [coeff_a_fi; coeff_b_fi];

            for i = 1:length(coeff_data)
                try
                    % 检查地址索引
                    if i > length(addresses)
                        break;
                    end

                    % 组合 24 位数据：6 位地址 + 18 位数据
                    address = addresses(i);
                    if coeff_data(i) < 0
                        data = bitand(int32(coeff_data(i)), 2^18 - 1);  % 负数取18位补码
                    else
                        data = uint32(coeff_data(i));  % 正数直接转换
                    end
           
                    disp(data);
                    % 将地址左移18位并与数据组合为24位
                    packet = bitshift(address, 18) + data;

                    % 转换为24位二进制字符串，再分段为8位HEX并带空格
                    hexData = sprintf('%02X %02X %02X', bitshift(packet, -16), bitand(bitshift(packet, -8), 255), bitand(packet, 255));
             

                    % 将 HEX 字符串转换为字节数组
                    byteData = uint8(sscanf(hexData, '%2x').');

                    % 发送数据到串口
                    write(app.SerialObject, byteData, "uint8");

                    % 在 SerialSend 控件中显示发送的数据
                    app.SerialSend.Value = hexData;

                    % 添加发送间隔，避免发送过快
                    pause(0.01); % 10ms 间隔
                catch
                    errordlg('error: 串口未连接！');
                    return;
                end
            end

            disp('系数已发送。');
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            % 获取当前滑块值
  
            value = round(app.Slider_3.Value);
            gain = 10^(value / 20);


            % 设定采样率和频率范围
            Fs = 48000;
            f1 = 320;
            f2 = 1300;

            % 调用封装的函数生成滤波器系数文件
            generate_filter_coefficients(Fs, f1, f2, gain);

            % 从生成的文件中读取系数
            coeff_a_file = './data/coeff_a_gain1.dat'; % 假设增益文件生成名称可以统一处理
            coeff_b_file = './data/coeff_b_gain1.dat'; % 修改为实际增益值的文件路径

            try
                coeff_a_fi = load(coeff_a_file);
                coeff_b_fi = load(coeff_b_file);
            catch
                errordlg('系数文件未找到或加载失败');
                return;
            end
            % 3. 定义地址 (添加新地址范围)
            addresses = [27, 28, 29, 30, 31, 32]; % 使用 15 到 20 作为地址

            % 4. 格式化并发送数据
            coeff_data = [coeff_a_fi; coeff_b_fi];

            for i = 1:length(coeff_data)
                try
                    % 检查地址索引
                    if i > length(addresses)
                        break;
                    end

                    % 组合 24 位数据：6 位地址 + 18 位数据
                    address = addresses(i);
                    if coeff_data(i) < 0
                        data = bitand(int32(coeff_data(i)), 2^18 - 1);  % 负数取18位补码
                    else
                        data = uint32(coeff_data(i));  % 正数直接转换
                    end
           
                    disp(data);
                    % 将地址左移18位并与数据组合为24位
                    packet = bitshift(address, 18) + data;

                    % 转换为24位二进制字符串，再分段为8位HEX并带空格
                    hexData = sprintf('%02X %02X %02X', bitshift(packet, -16), bitand(bitshift(packet, -8), 255), bitand(packet, 255));
             

                    % 将 HEX 字符串转换为字节数组
                    byteData = uint8(sscanf(hexData, '%2x').');

                    % 发送数据到串口
                    write(app.SerialObject, byteData, "uint8");

                    % 在 SerialSend 控件中显示发送的数据
                    app.SerialSend.Value = hexData;

                    % 添加发送间隔，避免发送过快
                    pause(0.01); % 10ms 间隔
                catch
                    errordlg('error: 串口未连接！');
                    return;
                end
            end

            disp('系数已发送。');
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            % 获取当前滑块值
            value = round(app.Slider_4.Value);
            gain = 10^(value / 20);

            % 设定采样率和频率范围
            Fs = 48000;
            f1 = 1300;
            f2 = 2600;

            % 调用封装的函数生成滤波器系数文件
            generate_filter_coefficients(Fs, f1, f2, gain);

            % 从生成的文件中读取系数
            coeff_a_file = './data/coeff_a_gain1.dat'; % 假设增益文件生成名称可以统一处理
            coeff_b_file = './data/coeff_b_gain1.dat'; % 修改为实际增益值的文件路径

            try
                coeff_a_fi = load(coeff_a_file);
                coeff_b_fi = load(coeff_b_file);
            catch
                errordlg('系数文件未找到或加载失败');
                return;
            end
            % 3. 定义地址 (添加新地址范围)
            addresses = [33, 34, 35, 36, 37, 38]; % 使用 15 到 20 作为地址

            % 4. 格式化并发送数据
            coeff_data = [coeff_a_fi; coeff_b_fi];

            for i = 1:length(coeff_data)
                try
                    % 检查地址索引
                    if i > length(addresses)
                        break;
                    end

                    % 组合 24 位数据：6 位地址 + 18 位数据
                    address = addresses(i);
                    if coeff_data(i) < 0
                        data = bitand(int32(coeff_data(i)), 2^18 - 1);  % 负数取18位补码
                    else
                        data = uint32(coeff_data(i));  % 正数直接转换
                    end
           
                    disp(data);
                    % 将地址左移18位并与数据组合为24位
                    packet = bitshift(address, 18) + data;

                    % 转换为24位二进制字符串，再分段为8位HEX并带空格
                    hexData = sprintf('%02X %02X %02X', bitshift(packet, -16), bitand(bitshift(packet, -8), 255), bitand(packet, 255));
             

                    % 将 HEX 字符串转换为字节数组
                    byteData = uint8(sscanf(hexData, '%2x').');

                    % 发送数据到串口
                    write(app.SerialObject, byteData, "uint8");

                    % 在 SerialSend 控件中显示发送的数据
                    app.SerialSend.Value = hexData;

                    % 添加发送间隔，避免发送过快
                    pause(0.01); % 10ms 间隔
                catch
                    errordlg('error: 串口未连接！');
                    return;
                end
            end

            disp('系数已发送。');
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            % 获取当前滑块值

            value = round(app.Slider_5.Value);
            gain = 10^(value / 20);

            % 设定采样率和频率范围
            Fs = 48000;
            f1 = 2600;
            f2 = 5200;

            % 调用封装的函数生成滤波器系数文件
            generate_filter_coefficients(Fs, f1, f2, gain);

            % 从生成的文件中读取系数
            coeff_a_file = './data/coeff_a_gain1.dat'; % 假设增益文件生成名称可以统一处理
            coeff_b_file = './data/coeff_b_gain1.dat'; % 修改为实际增益值的文件路径

            try
                coeff_a_fi = load(coeff_a_file);
                coeff_b_fi = load(coeff_b_file);
            catch
                errordlg('系数文件未找到或加载失败');
                return;
            end
            % 3. 定义地址 (添加新地址范围)
            addresses = [39, 40, 41, 42, 43, 44]; % 使用 15 到 20 作为地址

            % 4. 格式化并发送数据
            coeff_data = [coeff_a_fi; coeff_b_fi];

            for i = 1:length(coeff_data)
                try
                    % 检查地址索引
                    if i > length(addresses)
                        break;
                    end

                    % 组合 24 位数据：6 位地址 + 18 位数据
                    address = addresses(i);
                    if coeff_data(i) < 0
                        data = bitand(int32(coeff_data(i)), 2^18 - 1);  % 负数取18位补码
                    else
                        data = uint32(coeff_data(i));  % 正数直接转换
                    end
           
                    disp(data);
                    % 将地址左移18位并与数据组合为24位
                    packet = bitshift(address, 18) + data;

                    % 转换为24位二进制字符串，再分段为8位HEX并带空格
                    hexData = sprintf('%02X %02X %02X', bitshift(packet, -16), bitand(bitshift(packet, -8), 255), bitand(packet, 255));
             

                    % 将 HEX 字符串转换为字节数组
                    byteData = uint8(sscanf(hexData, '%2x').');

                    % 发送数据到串口
                    write(app.SerialObject, byteData, "uint8");

                    % 在 SerialSend 控件中显示发送的数据
                    app.SerialSend.Value = hexData;

                    % 添加发送间隔，避免发送过快
                    pause(0.01); % 10ms 间隔
                catch
                    errordlg('error: 串口未连接！');
                    return;
                end
            end

            disp('系数已发送。');
        end

        % Value changed function: EchoSwitch_2
        function EchoSwitch_2ValueChanged(app, event)
            value = app.EchoSwitch_2.Value;
            try
                % 判断开关状态并发送对应的数据
                if strcmp(value, '打开')
                    % 开状态下发送 24 位 HEX 数据 00 00 04
                    hexData = "F4 00 04";
                    app.echo_state_2.Color=[0,1,0];%绿色  将指示灯打开
                elseif strcmp(value, '关闭')
                    % 关状态下发送 24 位 HEX 数据 00 00 00
                    hexData = "F4 00 00";
                    app.echo_state_2.Color=[1,1,1];%白色  将指示灯关闭
                end
                % 将 HEX 数据转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');
                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");
                % 显示发送的数据在 SerialSend 控件中
                app.SerialSend.Value = hexData;
            catch
                errordlg('error: 串口未连接！');
            end
        end

        % Value changed function: EchoTimeSlider_2
        function EchoTimeSlider_2ValueChanged(app, event)
                try
                % 检查串口是否已打开
                numel(app.SerialObject); % 如果串口未连接，将会抛出异常

                % 获取滑动条当前整数值
                sliderValue = round(app.EchoTimeSlider_2.Value);

                % 将当前值显示在标签上
                app.EchoTimeLabel_2.Text = num2str(sliderValue);

                % 将滑动条值乘以48，并确保是整数
                value = sliderValue * 48;

                % 将结果转换为18位二进制字符串，不足前面补零
                binaryValue = dec2bin(value, 18);

                % 地址为6位二进制 '010001'，即6'd7
                addressBinary = '000111';

                % 合并地址和数据，形成24位二进制字符串
                combinedBinary = strcat(addressBinary, binaryValue);

                % 将24位二进制转换为3个字节的十六进制字符串格式
                hexData = [dec2hex(bin2dec(combinedBinary(1:8)), 2), ' ', ...
                    dec2hex(bin2dec(combinedBinary(9:16)), 2), ' ', ...
                    dec2hex(bin2dec(combinedBinary(17:24)), 2)];

                % 将HEX数据字符串转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');

                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");

                % 在SerialSend控件上显示发送的数据
                app.SerialSend.Value = hexData;

            catch
                errordlg('error: 串口未连接！');
            end
        end

        % Value changed function: EchoSwitch_3
        function EchoSwitch_3ValueChanged(app, event)
            value = app.EchoSwitch_3.Value;
            try
                % 判断开关状态并发送对应的数据
                if strcmp(value, '打开')
                    % 开状态下发送 24 位 HEX 数据 00 00 04
                    hexData = "F4 00 01";
                    app.echo_state_3.Color=[0,1,0];%绿色  将指示灯打开
                elseif strcmp(value, '关闭')
                    % 关状态下发送 24 位 HEX 数据 00 00 00
                    hexData = "F4 00 00";
                    app.echo_state_3.Color=[1,1,1];%白色  将指示灯关闭
                end
                % 将 HEX 数据转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');
                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");
                % 显示发送的数据在 SerialSend 控件中
                app.SerialSend.Value = hexData;
            catch
                errordlg('error: 串口未连接！');
            end
        end

        % Value changed function: EchoTimeSlider_3
        function EchoTimeSlider_3ValueChanged(app, event)
            try
                % 检查串口是否已打开
                numel(app.SerialObject); % 如果串口未连接，将会抛出异常

                % 获取滑动条当前整数值
                sliderValue = round(app.EchoTimeSlider_3.Value);

                % 将当前值显示在标签上
                app.EchoTimeLabel_3.Text = num2str(sliderValue);

                % 将结果转换为18位二进制字符串，不足前面补零
                binaryValue = dec2bin(sliderValue, 18);

                % 地址为6位二进制 '010001'，即6'd45
                addressBinary = '101101';

                % 合并地址和数据，形成24位二进制字符串
                combinedBinary = strcat(addressBinary, binaryValue);

                % 将24位二进制转换为3个字节的十六进制字符串格式
                hexData = [dec2hex(bin2dec(combinedBinary(1:8)), 2), ' ', ...
                    dec2hex(bin2dec(combinedBinary(9:16)), 2), ' ', ...
                    dec2hex(bin2dec(combinedBinary(17:24)), 2)];

                % 将HEX数据字符串转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');

                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");

                % 在SerialSend控件上显示发送的数据
                app.SerialSend.Value = hexData;

            catch
                errordlg('error: 串口未连接！');
            end
        end

        % Value changed function: EchoSwitch_4
        function EchoSwitch_4ValueChanged(app, event)
            value = app.EchoSwitch_4.Value;
            try
                % 判断开关状态并发送对应的数据
                if strcmp(value, '打开')
                    % 开状态下发送 24 位 HEX 数据 00 00 04
                    hexData = "F4 00 04";
                    app.echo_state_4.Color=[0,1,0];%绿色  将指示灯打开
                elseif strcmp(value, '关闭')
                    % 关状态下发送 24 位 HEX 数据 00 00 00
                    hexData = "F4 00 00";
                    app.echo_state_4.Color=[1,1,1];%白色  将指示灯关闭
                end
                % 将 HEX 数据转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');
                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");
                % 显示发送的数据在 SerialSend 控件中
                app.SerialSend.Value = hexData;
            catch
                errordlg('error: 串口未连接！');
            end
        end

        % Value changed function: EchoTimeSlider_4
        function EchoTimeSlider_4ValueChanged(app, event)
                try
                % 检查串口是否已打开
                numel(app.SerialObject); % 如果串口未连接，将会抛出异常

                % 获取滑动条当前整数值
                sliderValue = round(app.EchoTimeSlider_4.Value);

                % 将当前值显示在标签上
                app.EchoTimeLabel_4.Text = num2str(sliderValue);

                % 将滑动条值乘以48，并确保是整数
                value = sliderValue * 48;

                % 将结果转换为18位二进制字符串，不足前面补零
                binaryValue = dec2bin(value, 18);

                % 地址为6位二进制 '010001'，即6'd7
                addressBinary = '000111';

                % 合并地址和数据，形成24位二进制字符串
                combinedBinary = strcat(addressBinary, binaryValue);

                % 将24位二进制转换为3个字节的十六进制字符串格式
                hexData = [dec2hex(bin2dec(combinedBinary(1:8)), 2), ' ', ...
                    dec2hex(bin2dec(combinedBinary(9:16)), 2), ' ', ...
                    dec2hex(bin2dec(combinedBinary(17:24)), 2)];

                % 将HEX数据字符串转换为字节数组
                byteData = uint8(sscanf(hexData, '%2x').');

                % 发送数据到串口
                write(app.SerialObject, byteData, "uint8");

                % 在SerialSend控件上显示发送的数据
                app.SerialSend.Value = hexData;

            catch
                errordlg('error: 串口未连接！');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '波特率';
            app.SerialBaud = uidropdown(app.UIFigure);
            app.SerialBaud.Items = {'2400', '4800', '9600', '19200', '38400', '57600', '115200', '128000'};
            app.SerialBaud.Value = '115200';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '串口操作';
            app.SerialSwitch = uiswitch(app.UIFigure, 'slider');
            app.SerialSwitch.Items = {'打开', '关闭'};
            app.SerialSwitch.ValueChangedFcn = createCallbackFcn(app, @SerialSwitchValueChanged, true);
            app.SerialSwitch.Value = '关闭';
            app.setLabel = uilabel(app.UIFigure);
            app.setLabel.Text = '串口设置';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '停止位';
            app.SerialStopBit = uidropdown(app.UIFigure);
            app.SerialStopBit.Items = {'1', '1.5', '2'};
            app.SerialStopBit.Value = '1';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '串口号';
            app.SerialPortNums = uidropdown(app.UIFigure);
            app.SerialPortNums.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortNumsDropDownOpening, true);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '校验位';
            app.SerialParity = uidropdown(app.UIFigure);
            app.SerialParity.Items = {'None', 'odd', 'Even'};
            app.SerialParity.Value = 'None';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '串口接受';
            app.SerialReceiver = uieditfield(app.UIFigure, 'text');
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '';
            app.serial_state = uilamp(app.UIFigure);
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '串口发送';
            app.SerialSend = uieditfield(app.UIFigure, 'text');
            app.EchoSwitch = uiswitch(app.UIFigure, 'slider');
            app.EchoSwitch.Items = {'打开', '关闭'};
            app.EchoSwitch.ValueChangedFcn = createCallbackFcn(app, @EchoSwitchValueChanged, true);
            app.EchoSwitch.Value = '关闭';
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '回声';
            app.echo_state = uilamp(app.UIFigure);
            app.reverb_state = uilamp(app.UIFigure);
            app.ReverbSwitch = uiswitch(app.UIFigure, 'slider');
            app.ReverbSwitch.Items = {'打开', '关闭'};
            app.ReverbSwitch.ValueChangedFcn = createCallbackFcn(app, @ReverbSwitchValueChanged, true);
            app.ReverbSwitch.Value = '关闭';
            app.Label_21 = uilabel(app.UIFigure);
            app.Label_21.Text = '混响';
            app.eqa_state = uilamp(app.UIFigure);
            app.EqaSwitch = uiswitch(app.UIFigure, 'slider');
            app.EqaSwitch.Items = {'打开', '关闭'};
            app.EqaSwitch.ValueChangedFcn = createCallbackFcn(app, @EqaSwitchValueChanged, true);
            app.EqaSwitch.Value = '关闭';
            app.Label_22 = uilabel(app.UIFigure);
            app.Label_22.Text = '均衡器';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'serial_communication_resources', '7CBC1E9F0E-1877x497.png.svg');
            app.Label_24 = uilabel(app.UIFigure);
            app.Label_24.Text = '延迟时间';
            app.EchoTimeSlider = uislider(app.UIFigure);
            app.EchoTimeSlider.Limits = [0 170];
            app.EchoTimeSlider.ValueChangedFcn = createCallbackFcn(app, @EchoTimeSliderValueChanged, true);
            app.EchoTimeLabel = uilabel(app.UIFigure);
            app.EchoTimeLabel.Text = '0';
            app.mSLabel = uilabel(app.UIFigure);
            app.mSLabel.Text = 'mS';
            app.ReverbTimeLabel = uilabel(app.UIFigure);
            app.ReverbTimeLabel.Text = '0';
            app.mSLabel_1 = uilabel(app.UIFigure);
            app.mSLabel_1.Text = 'mS';
            app.Label_25 = uilabel(app.UIFigure);
            app.Label_25.Text = '混响时间';
            app.ReverbTimeSlider = uislider(app.UIFigure);
            app.ReverbTimeSlider.Limits = [0 21];
            app.ReverbTimeSlider.ValueChangedFcn = createCallbackFcn(app, @ReverbTimeSliderValueChanged, true);
            app.DownSample_state = uilamp(app.UIFigure);
            app.Sample = uilabel(app.UIFigure);
            app.Sample.Text = '48';
            app.Label_29 = uilabel(app.UIFigure);
            app.Label_29.Text = '采样率';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'12', '16', '24'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DownValueChanged, true);
            app.DropDown.Value = '12';
            app.DownSampleSwitch = uiswitch(app.UIFigure, 'slider');
            app.DownSampleSwitch.Items = {'打开', '关闭'};
            app.DownSampleSwitch.ValueChangedFcn = createCallbackFcn(app, @DownSampleSwitchChanged, true);
            app.DownSampleSwitch.Value = '关闭';
            app.Label_30 = uilabel(app.UIFigure);
            app.Label_30.Text = '降采样';
            app.UpSample_state = uilamp(app.UIFigure);
            app.UpSampleNum = uidropdown(app.UIFigure);
            app.UpSampleNum.Items = {'96', '192', '384'};
            app.UpSampleNum.ValueChangedFcn = createCallbackFcn(app, @UpSampleNumValueChanged, true);
            app.UpSampleNum.Value = '96';
            app.UpSampleSwitch = uiswitch(app.UIFigure, 'slider');
            app.UpSampleSwitch.Items = {'打开', '关闭'};
            app.UpSampleSwitch.ValueChangedFcn = createCallbackFcn(app, @UpSampleSwitchChanged, true);
            app.UpSampleSwitch.Value = '关闭';
            app.Label_31 = uilabel(app.UIFigure);
            app.Label_31.Text = '升采样';
            app.Slider_1 = uislider(app.UIFigure);
            app.Slider_1.Limits = [-30 30];
            app.Slider_1.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged_1, true);
            app.Slider_1.Tag = '1';
            app.dBLabel = uilabel(app.UIFigure);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.UIFigure);
            app.dBLabel_2.Text = 'dB';
            app.dBLabel_3 = uilabel(app.UIFigure);
            app.dBLabel_3.Text = 'dB';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-30 30];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Tag = '2';
            app.dBLabel_4 = uilabel(app.UIFigure);
            app.dBLabel_4.Text = 'dB';
            app.dBLabel_5 = uilabel(app.UIFigure);
            app.dBLabel_5.Text = 'dB';
            app.dBLabel_6 = uilabel(app.UIFigure);
            app.dBLabel_6.Text = 'dB';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [-30 30];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.Tag = '3';
            app.dBLabel_7 = uilabel(app.UIFigure);
            app.dBLabel_7.Text = 'dB';
            app.dBLabel_8 = uilabel(app.UIFigure);
            app.dBLabel_8.Text = 'dB';
            app.dBLabel_9 = uilabel(app.UIFigure);
            app.dBLabel_9.Text = 'dB';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [-30 30];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.Tag = '4';
            app.dBLabel_10 = uilabel(app.UIFigure);
            app.dBLabel_10.Text = 'dB';
            app.dBLabel_11 = uilabel(app.UIFigure);
            app.dBLabel_11.Text = 'dB';
            app.dBLabel_12 = uilabel(app.UIFigure);
            app.dBLabel_12.Text = 'dB';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [-30 30];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.Tag = '5';
            app.dBLabel_13 = uilabel(app.UIFigure);
            app.dBLabel_13.Text = 'dB';
            app.dBLabel_14 = uilabel(app.UIFigure);
            app.dBLabel_14.Text = 'dB';
            app.dBLabel_15 = uilabel(app.UIFigure);
            app.dBLabel_15.Text = 'dB';
            app.Label_32 = uilabel(app.UIFigure);
            app.Label_32.Text = '滤波器截止频率';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.Value = 10000;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'serial_communication_resources', 'icon_48.png');
            app.Button.Text = '确认';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = '80 Hz';
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = '320 Hz';
            app.KHzLabel = uilabel(app.UIFigure);
            app.KHzLabel.Text = '1.3K Hz';
            app.KHzLabel_2 = uilabel(app.UIFigure);
            app.KHzLabel_2.Text = '2.6K Hz';
            app.KHzLabel_3 = uilabel(app.UIFigure);
            app.KHzLabel_3.Text = '5.2K Hz';
            app.echo_state_2 = uilamp(app.UIFigure);
            app.EchoTimeLabel_2 = uilabel(app.UIFigure);
            app.EchoTimeLabel_2.Text = '0';
            app.mSLabel_2 = uilabel(app.UIFigure);
            app.mSLabel_2.Text = 'mS';
            app.EchoSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.EchoSwitch_2.Items = {'打开', '关闭'};
            app.EchoSwitch_2.ValueChangedFcn = createCallbackFcn(app, @EchoSwitch_2ValueChanged, true);
            app.EchoSwitch_2.Value = '关闭';
            app.Label_33 = uilabel(app.UIFigure);
            app.Label_33.Text = '合唱';
            app.Label_34 = uilabel(app.UIFigure);
            app.Label_34.Text = '合唱延时';
            app.EchoTimeSlider_2 = uislider(app.UIFigure);
            app.EchoTimeSlider_2.Limits = [10 70];
            app.EchoTimeSlider_2.ValueChangedFcn = createCallbackFcn(app, @EchoTimeSlider_2ValueChanged, true);
            app.EchoTimeSlider_2.Value = 10;
            app.echo_state_3 = uilamp(app.UIFigure);
            app.EchoSwitch_3 = uiswitch(app.UIFigure, 'slider');
            app.EchoSwitch_3.Items = {'打开', '关闭'};
            app.EchoSwitch_3.ValueChangedFcn = createCallbackFcn(app, @EchoSwitch_3ValueChanged, true);
            app.EchoSwitch_3.Value = '关闭';
            app.Label_35 = uilabel(app.UIFigure);
            app.Label_35.Text = '限幅失真';
            app.EchoTimeLabel_3 = uilabel(app.UIFigure);
            app.EchoTimeLabel_3.Text = '0';
            app.Label_36 = uilabel(app.UIFigure);
            app.Label_36.Text = '限幅大小';
            app.EchoTimeSlider_3 = uislider(app.UIFigure);
            app.EchoTimeSlider_3.Limits = [1024 16384];
            app.EchoTimeSlider_3.MajorTickLabels = {'1024', '3584', '6144', '8704', '11260', '13820', '16384'};
            app.EchoTimeSlider_3.ValueChangedFcn = createCallbackFcn(app, @EchoTimeSlider_3ValueChanged, true);
            app.EchoTimeSlider_3.Value = 1024;
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'serial_communication_resources', '1210.png');
            app.echo_state_4 = uilamp(app.UIFigure);
            app.EchoTimeLabel_4 = uilabel(app.UIFigure);
            app.EchoTimeLabel_4.Text = '0';
            app.mSLabel_3 = uilabel(app.UIFigure);
            app.mSLabel_3.Text = 'mS';
            app.EchoSwitch_4 = uiswitch(app.UIFigure, 'slider');
            app.EchoSwitch_4.Items = {'打开', '关闭'};
            app.EchoSwitch_4.ValueChangedFcn = createCallbackFcn(app, @EchoSwitch_4ValueChanged, true);
            app.EchoSwitch_4.Value = '关闭';
            app.Label_37 = uilabel(app.UIFigure);
            app.Label_37.Text = '镶边效果';
            app.Label_38 = uilabel(app.UIFigure);
            app.Label_38.Text = '镶边延时';
            app.EchoTimeSlider_4 = uislider(app.UIFigure);
            app.EchoTimeSlider_4.Limits = [0 35];
            app.EchoTimeSlider_4.ValueChangedFcn = createCallbackFcn(app, @EchoTimeSlider_4ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = serial_communication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end